classdef projekt_v1_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BackgroundCheckBox           matlab.ui.control.CheckBox
        SourceDropDown               matlab.ui.control.DropDown
        SourceDropDownLabel          matlab.ui.control.Label
        PulseCheckBox                matlab.ui.control.CheckBox
        FrequencySlider_2            matlab.ui.control.Slider
        FrequencySlider_2Label       matlab.ui.control.Label
        PhaseSlider_2                matlab.ui.control.Slider
        PhaseSlider_2Label           matlab.ui.control.Label
        AmplitudeSlider_2            matlab.ui.control.Slider
        AmplitudeSlider_2Label       matlab.ui.control.Label
        DampingSlider_2              matlab.ui.control.Slider
        DampingSlider_2Label         matlab.ui.control.Label
        PropagationSlider            matlab.ui.control.Slider
        PropagationSliderLabel       matlab.ui.control.Label
        FrequencySlider              matlab.ui.control.Slider
        FrequencySliderLabel         matlab.ui.control.Label
        PhaseSlider                  matlab.ui.control.Slider
        PhaseSliderLabel             matlab.ui.control.Label
        AmplitudeSlider              matlab.ui.control.Slider
        AmplitudeSliderLabel         matlab.ui.control.Label
        DampingSlider                matlab.ui.control.Slider
        DampingSliderLabel           matlab.ui.control.Label
        SecondSourceCheckBox         matlab.ui.control.CheckBox
        BorderCheckBox               matlab.ui.control.CheckBox
        SideViewButton               matlab.ui.control.Button
        DiagonalViewButton           matlab.ui.control.Button
        TopViewButton                matlab.ui.control.Button
        ColormapDropDown             matlab.ui.control.DropDown
        ColormapDropDownLabel        matlab.ui.control.Label
        SurfaceCheckBox              matlab.ui.control.CheckBox
        AnimationTImeEditField       matlab.ui.control.NumericEditField
        AnimationTImeEditFieldLabel  matlab.ui.control.Label
        RunButton                    matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Lx = 10;
        Ly = 10;
        dx = .1;
        dy = .1;
        CFL = .5;
        nx
        ny
        x
        y
        T
        wn
        wnl
        wnp
        cview
        beta
        c = 1;
        ec
        cax
        spx = 50
        spy = 50
        spx1 = 25
        spy1 = 75
        spx2 = 75
        spy2 = 25
        
      
    end
    
    methods (Access = private)
        
        
        function  updatePlot(app,t)
            
            
            app.cax = axis(app.UIAxes,'state');
            if app.SurfaceCheckBox.Value == 0
                mesh(app.UIAxes,app.x,app.y,app.wn);
            else
                surf(app.UIAxes,app.x,app.y,app.wn,EdgeColor = 'texturemap',FaceAlpha=1);              
            end
            
            view(app.UIAxes,app.cview)
            title(app.UIAxes,sprintf("T = %0.2f",t));
            colormap(app.UIAxes,app.ColormapDropDown.Value);

            clim(app.UIAxes,[-0.03 0.03]);
            
            axis(app.UIAxes,app.cax);
%             colorbar(app.UIAxes,'Ticks',[]);
            grid(app.UIAxes,"off");
            pause(0.001);

        end
        
        function switchEnableSS(app)
            if app.SecondSourceCheckBox.Value ==0
                app.PhaseSlider_2.Enable = "off";
                app.AmplitudeSlider_2.Enable = "off";
                app.DampingSlider_2.Enable = "off";
                app.FrequencySlider_2.Enable = "off";
            else
                app.PhaseSlider_2.Enable = "on";
                app.AmplitudeSlider_2.Enable = "on";
                app.DampingSlider_2.Enable = "on";
                app.FrequencySlider_2.Enable = "on";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.T = app.AnimationTImeEditField.Value;

%             app.sposition1 = 

            app.nx = fix(app.Lx/app.dx);
            app.ny = fix(app.Ly/app.dy);

            app.x = linspace(0,app.Lx,app.nx);
            app.y = linspace(0,app.Ly,app.ny);

            app.wn = zeros(app.nx,app.ny);
            app.wnl = zeros(app.nx,app.ny);
            app.wnp = zeros(app.nx,app.ny);

            mesh(app.UIAxes,app.x,app.y,app.wn);

            app.cview=app.UIAxes.View();
            
            zlim(app.UIAxes,[-0.05 0.05]);
            zticks(app.UIAxes,[-0.05 -0.025 0 0.025 0.05]);
            zticklabels(app.UIAxes,{'-A','-0.5A','0','0.5A','A'});
            
            set(app.UIAxes,'color','none');

            app.cax = axis(app.UIAxes,'state');
%             colorbar(app.UIAxes,'Ticks',[]);
            clim(app.UIAxes,[-0.02 0.02]);

    
            app.beta = app.DampingSlider.Value;

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            t=0;
            app.cview=app.UIAxes.View();
            

            dt = app.CFL*app.dx/app.c;
            
            
            app.wn = zeros(app.nx,app.ny);
            app.wnl = zeros(app.nx,app.ny);
            app.wnp = zeros(app.nx,app.ny);
        

            while(t<app.T)
                

                if app.SourceDropDown.Value == "one slit" || app.SourceDropDown.Value == "double slit"
                    app.wnp(:,end) = app.wnp(:,end-1);
                    app.wnp(end,:) = app.wn(end-1,:) +(app.CFL-1)/(app.CFL +1)*(app.wnp(end - 1,:)-app.wn(end,:));
                    app.wnp(1,:) = app.wn(2,:) +(app.CFL-1)/(app.CFL +1)*(app.wnp(2,:)-app.wn(1,:));
                    app.wnp(:,1) = app.wn(:,2) +(app.CFL-1)/(app.CFL +1)*(app.wnp(:,2)-app.wn(:,1));
                    if app.SourceDropDown.Value == "one slit"
                        app.wnp(1:47,50) = 0;
                        app.wnp(53:end,50) = 0;
                    else
                        app.wnp(1:40,50) = 0;
                        app.wnp(45:55,50) = 0;
                        app.wnp(60:end,50) = 0;
                    end
                else
                
                    if app.BorderCheckBox.Value == 0
                            app.wnp(:,1) = app.wn(:,2) +(app.CFL-1)/(app.CFL +1)*(app.wnp(:,2)-app.wn(:,1));
                            app.wnp(:,end) = app.wn(:,end-1) +(app.CFL-1)/(app.CFL +1)*(app.wnp(:,end-1)-app.wn(:,end));
                            
                        if app.SourceDropDown.Value == "full edge"
                            app.wnp([1 end],:) = app.wnp([2 end-1],:);
                        else
                            app.wnp(end,:) = app.wn(end-1,:) +(app.CFL-1)/(app.CFL +1)*(app.wnp(end - 1,:)-app.wn(end,:));
                            app.wnp(1,:) = app.wn(2,:) +(app.CFL-1)/(app.CFL +1)*(app.wnp(2,:)-app.wn(1,:));
                        end
                    else
                       app.wnp(:,[1 end]) = app.wnp(:,[2 end-1]);
                       app.wnp([1 end],:) = app.wnp([2 end-1],:);
                    end
                end
                t = t+dt;
                app.wnl = app.wn;
                app.wn = app.wnp;
            
      
                if app.SecondSourceCheckBox.Value == 0
                    if app.PulseCheckBox.Value == 0 || t<1/app.FrequencySlider.Value
                        app.wn(app.spx,app.spy) = app.AmplitudeSlider.Value*sin(app.FrequencySlider.Value*2*pi*t - app.PhaseSlider.Value);
                    end
                else
                    if app.PulseCheckBox.Value == 0 || t<1/app.FrequencySlider.Value
                        app.wn(app.spx1,app.spy1) = app.AmplitudeSlider.Value*sin(app.FrequencySlider.Value*2*pi*t - app.PhaseSlider.Value);
                    end
                    if app.PulseCheckBox.Value == 0 || t<1/app.FrequencySlider_2.Value
                        app.wn(app.spx2,app.spy2) = app.AmplitudeSlider_2.Value*sin(app.FrequencySlider_2.Value*2*pi*t - app.PhaseSlider_2.Value);
                    end
                end

                for i=2:app.nx-1
                    for j = 2:app.ny-1 
                            app.wnp(i,j) = 2*app.wn(i,j) - app.wnl(i,j) + (app.CFL)^2*(app.wn(i+1,j)+app.wn(i-1,j)...
                                +app.wn(i,j+1)+app.wn(i,j-1) - 4*app.wn(i,j)) - app.beta*dt*(app.wn(i,j)-app.wnl(i,j));
                    end
                end
            
                  updatePlot(app,t);      
            end
            
            
        end

        % Value changed function: AnimationTImeEditField
        function AnimationTImeEditFieldValueChanged(app, event)
            value = app.AnimationTImeEditField.Value;
            app.T = value;
        end

        % Button pushed function: SideViewButton
        function SideViewButtonPushed(app, event)
            app.cview = [0 0];
            view(app.UIAxes,app.cview)
        end

        % Button pushed function: DiagonalViewButton
        function DiagonalViewButtonPushed(app, event)
            app.cview = [135 0];
            view(app.UIAxes,app.cview)
        end

        % Button pushed function: TopViewButton
        function TopViewButtonPushed(app, event)
            app.cview = [0 90];
            view(app.UIAxes,app.cview)
        end

        % Value changed function: SurfaceCheckBox
        function SurfaceCheckBoxValueChanged(app, event)
            app.cview=app.UIAxes.View();
            updatePlot(app,0);
        end

        % Value changed function: DampingSlider
        function DampingSliderValueChanged(app, event)
            value = app.DampingSlider.Value;
            app.beta     = value;
        end

        % Value changed function: PropagationSlider
        function PropagationSliderValueChanged(app, event)
            value = app.PropagationSlider.Value;
            app.c = value;
        end

        % Value changed function: SecondSourceCheckBox
        function SecondSourceCheckBoxValueChanged(app, event)
            

            app.switchEnableSS();
            
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
%             value = app.ColormapDropDown.Value;
            app.cview=app.UIAxes.View();
            updatePlot(app,0);
        end

        % Value changed function: SourceDropDown
        function SourceDropDownValueChanged(app, event)
            value = app.SourceDropDown.Value;
            if  value == "one slit" || value == "double slit" 
                app.SecondSourceCheckBox.Value = 0;
                app.SecondSourceCheckBox.Enable ="off";
                app.switchEnableSS();

                app.BorderCheckBox.Enable ="off";
            else
                app.SecondSourceCheckBox.Enable ="on";
                app.switchEnableSS();

                app.BorderCheckBox.Enable ="on";
            end

            if value == "inside"
                app.spx = 50;
                app.spy = 50;
                app.spx1 = 25;
                app.spy1 = 75;
                app.spx2 = 75;
                app.spy2 = 25;
            else 
                if value == "corner"
                    app.spx = 2;
                    app.spy = 99;
                    app.spx1 = 2;
                    app.spy1 = 99;
                    app.spx2 = 99;
                    app.spy2 = 2;
                else
                    if value == "edge" || value == "one slit" || value == "double slit" 
                        app.spx = 50;
                        app.spy = 99;
                        app.spx1 = 50;
                        app.spy1 = 99;
                        app.spx2 = 50;
                        app.spy2 = 2;
                    else
                        if value == "full edge"
                        app.spx = 2:99;
                        app.spy = 50;
                        app.spx1 = 2:99;
                        app.spy1 = 2;
                        app.spx2 = 2:99;
                        app.spy2 = 99;
                        end
                    end
                end
            end
        end

        % Value changed function: BackgroundCheckBox
        function BackgroundCheckBoxValueChanged(app, event)
            value = app.BackgroundCheckBox.Value;
            if value == 0
                set(app.UIAxes,'color','none');
            else
                set(app.UIAxes,'color','w');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AnimationTImeEditFieldLabel = uilabel(app.UIFigure);
            app.AnimationTImeEditFieldLabel.Text = 'Animation TIme';
            app.AnimationTImeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnimationTImeEditField.Limits = [0 Inf];
            app.AnimationTImeEditField.ValueChangedFcn = createCallbackFcn(app, @AnimationTImeEditFieldValueChanged, true);
            app.AnimationTImeEditField.Value = 5;
            app.SurfaceCheckBox = uicheckbox(app.UIFigure);
            app.SurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @SurfaceCheckBoxValueChanged, true);
            app.SurfaceCheckBox.Text = 'Surface';
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {'default', 'turbo', 'cool', 'winter'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'default';
            app.TopViewButton = uibutton(app.UIFigure, 'push');
            app.TopViewButton.ButtonPushedFcn = createCallbackFcn(app, @TopViewButtonPushed, true);
            app.TopViewButton.Text = 'TopView';
            app.DiagonalViewButton = uibutton(app.UIFigure, 'push');
            app.DiagonalViewButton.ButtonPushedFcn = createCallbackFcn(app, @DiagonalViewButtonPushed, true);
            app.DiagonalViewButton.Text = 'DiagonalView';
            app.SideViewButton = uibutton(app.UIFigure, 'push');
            app.SideViewButton.ButtonPushedFcn = createCallbackFcn(app, @SideViewButtonPushed, true);
            app.SideViewButton.Text = 'SideView';
            app.BorderCheckBox = uicheckbox(app.UIFigure);
            app.BorderCheckBox.Text = 'Border';
            app.SecondSourceCheckBox = uicheckbox(app.UIFigure);
            app.SecondSourceCheckBox.ValueChangedFcn = createCallbackFcn(app, @SecondSourceCheckBoxValueChanged, true);
            app.SecondSourceCheckBox.Text = 'SecondSource';
            app.SecondSourceCheckBox.Value = true;
            app.DampingSliderLabel = uilabel(app.UIFigure);
            app.DampingSliderLabel.Text = 'Damping';
            app.DampingSlider = uislider(app.UIFigure);
            app.DampingSlider.Limits = [0 1];
            app.DampingSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.DampingSlider.ValueChangedFcn = createCallbackFcn(app, @DampingSliderValueChanged, true);
            app.DampingSlider.MinorTicks = [];
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.Limits = [0 0.05];
            app.AmplitudeSlider.MajorTicks = [0 0.0125 0.025 0.0375 0.05];
            app.AmplitudeSlider.MajorTickLabels = {'0', '0.25A', '0.5A', '0.75A', 'A'};
            app.AmplitudeSlider.MinorTicks = [];
            app.AmplitudeSlider.Value = 0.05;
            app.PhaseSliderLabel = uilabel(app.UIFigure);
            app.PhaseSliderLabel.Text = 'Phase';
            app.PhaseSlider = uislider(app.UIFigure);
            app.PhaseSlider.Limits = [0 6.28318530717959];
            app.PhaseSlider.MajorTicks = [0 1.5707963267949 3.14159265358979 4.71238898038469 6.28318530717959];
            app.PhaseSlider.MajorTickLabels = {'0', '0.5π', 'π', '1.5π', '2π'};
            app.PhaseSlider.MinorTicks = [];
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.Limits = [0 2];
            app.FrequencySlider.MajorTicks = [0 0.5 1 1.5 2];
            app.FrequencySlider.MinorTicks = [];
            app.FrequencySlider.Value = 1;
            app.PropagationSliderLabel = uilabel(app.UIFigure);
            app.PropagationSliderLabel.Text = 'Propagation';
            app.PropagationSlider = uislider(app.UIFigure);
            app.PropagationSlider.Limits = [1 3];
            app.PropagationSlider.MajorTicks = [1 1.5 2 2.5 3];
            app.PropagationSlider.ValueChangedFcn = createCallbackFcn(app, @PropagationSliderValueChanged, true);
            app.PropagationSlider.MinorTicks = [];
            app.PropagationSlider.Value = 1;
            app.DampingSlider_2Label = uilabel(app.UIFigure);
            app.DampingSlider_2Label.Text = 'Damping';
            app.DampingSlider_2 = uislider(app.UIFigure);
            app.DampingSlider_2.Limits = [0 1];
            app.DampingSlider_2.MajorTicks = [0 0.25 0.5 0.75 1];
            app.DampingSlider_2.MinorTicks = [];
            app.AmplitudeSlider_2Label = uilabel(app.UIFigure);
            app.AmplitudeSlider_2Label.Text = 'Amplitude';
            app.AmplitudeSlider_2 = uislider(app.UIFigure);
            app.AmplitudeSlider_2.Limits = [0 0.05];
            app.AmplitudeSlider_2.MajorTicks = [0 0.0125 0.025 0.0375 0.05];
            app.AmplitudeSlider_2.MajorTickLabels = {'0', '0.25A', '0.5A', '0.75A', 'A'};
            app.AmplitudeSlider_2.MinorTicks = [];
            app.AmplitudeSlider_2.Value = 0.05;
            app.PhaseSlider_2Label = uilabel(app.UIFigure);
            app.PhaseSlider_2Label.Text = 'Phase';
            app.PhaseSlider_2 = uislider(app.UIFigure);
            app.PhaseSlider_2.Limits = [0 6.28318530717959];
            app.PhaseSlider_2.MajorTicks = [0 1.5707963267949 3.14159265358979 4.71238898038469 6.28318530717959];
            app.PhaseSlider_2.MajorTickLabels = {'0', '0.5π', 'π', '1.5π', '2π'};
            app.PhaseSlider_2.MinorTicks = [];
            app.FrequencySlider_2Label = uilabel(app.UIFigure);
            app.FrequencySlider_2Label.Text = 'Frequency';
            app.FrequencySlider_2 = uislider(app.UIFigure);
            app.FrequencySlider_2.Limits = [0 2];
            app.FrequencySlider_2.MajorTicks = [0 0.5 1 1.5 2];
            app.FrequencySlider_2.MinorTicks = [];
            app.FrequencySlider_2.Value = 1;
            app.PulseCheckBox = uicheckbox(app.UIFigure);
            app.PulseCheckBox.Text = 'Pulse';
            app.SourceDropDownLabel = uilabel(app.UIFigure);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.UIFigure);
            app.SourceDropDown.Items = {'inside', 'corner', 'edge', 'full edge', 'one slit', 'double slit'};
            app.SourceDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceDropDownValueChanged, true);
            app.SourceDropDown.Value = 'inside';
            app.BackgroundCheckBox = uicheckbox(app.UIFigure);
            app.BackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @BackgroundCheckBoxValueChanged, true);
            app.BackgroundCheckBox.Text = 'Background';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projekt_v1_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end